classdef predict_svr_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SVRPredictionGUIUIFigure        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ModelNameLabel                  matlab.ui.control.Label
        SelectButton                    matlab.ui.control.Button
        SpinnerDays                     matlab.ui.control.Spinner
        DaystopredictLabel              matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        Dtlabel                         matlab.ui.control.Label
        SelectButtonDt                  matlab.ui.control.Button
        errorLabel                      matlab.ui.control.Label
        CreatedbyVladislavSorokinLabel  matlab.ui.control.Label
        SVRPREDICTIONLabel              matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        PredictionUi                    matlab.ui.control.UIAxes
        PercentageerrorsLabel           matlab.ui.control.Label
        Error1Label                     matlab.ui.control.Label
        Error2Label                     matlab.ui.control.Label
        Error3Label                     matlab.ui.control.Label
        Error4Label                     matlab.ui.control.Label
        Error5Label                     matlab.ui.control.Label
        MSELabel                        matlab.ui.control.Label
        MapeLabel                       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        FullPathToModel % Path to the model
        isModelSelected = false
        FullPathToDt
        isDtSelected = false
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
            else
               app.ModelNameLabel.Text = file;
               app.FullPathToModel = fullfile(path,file);
               app.isModelSelected = true;
            end
            drawnow;
            figure(app.SVRPredictionGUIUIFigure)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if (app.isModelSelected ~= true)
                app.errorLabel.Visible = true;
                app.errorLabel.Text = "Model is not selected";
                return;
            elseif (app.isDtSelected ~= true)
                app.errorLabel.Visible = true;
                app.errorLabel.Text = "Data is not selected";
                return;
            end
            app.PercentageerrorsLabel.Visible = false;
            app.Error1Label.Visible = false;
            app.Error2Label.Visible = false;
            app.Error3Label.Visible = false;
            app.Error4Label.Visible = false;
            app.Error5Label.Visible = false;
            app.MSELabel.Visible = false;
            app.MapeLabel.Visible = false;
            
            app.MSELabel.Visible = false;
            app.PredictionUi.Visible = true;
            cla(app.PredictionUi);
            app.errorLabel.Visible = false;
            mdl = load(app.FullPathToModel);
            [datespr, ypr, rst,errors,mse, Mape] = predict_svr(app.FullPathToDt,mdl.Mdl, app.SpinnerDays.Value);
            hold (app.PredictionUi, 'on')
            plot(app.PredictionUi,datespr(:,1),ypr,'-*')
            
            plot(app.PredictionUi,datespr(:,1),rst, '-o')
            hold (app.PredictionUi, 'off')
            legend(app.PredictionUi, 'Actual Value','Predicted Value');
            
            app.PercentageerrorsLabel.Visible = true;
            app.Error1Label.Visible = true;
            app.Error1Label.Text = "Day 1 " + num2str(errors(1))+ "%";
            if app.SpinnerDays.Value >= 2
                app.Error2Label.Visible = true;
                app.Error2Label.Text = "Day 2 " +num2str(errors(2))+ "%";
                
            end
            if app.SpinnerDays.Value >= 3
                app.Error3Label.Visible = true;
                app.Error3Label.Text = "Day 3 " + num2str(errors(3))+ "%";
                
            end
            if app.SpinnerDays.Value >= 4
                app.Error4Label.Visible = true;
                app.Error4Label.Text = "Day 4 " + num2str(errors(4)) + "%";
                
            end
            if app.SpinnerDays.Value >= 5
                app.Error5Label.Visible = true;
                app.Error5Label.Text = "Day 5 " + num2str(errors(5))+ "%";
            end
            app.MSELabel.Visible = true;
            app.MSELabel.Text = "MSE " + num2str(mse);
            app.MapeLabel.Visible = true;
            app.MapeLabel.Text = "MAPE " + num2str(Mape) + "%";
        end

        % Button pushed function: SelectButtonDt
        function SelectButtonDtPushed(app, event)
            [file,path] = uigetfile('*.csv');
            if isequal(file,0)
            else
               app.Dtlabel.Text = file;
               app.FullPathToDt = fullfile(path,file);
               app.isDtSelected = true;
            end
            drawnow;
            figure(app.SVRPredictionGUIUIFigure)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SVRPredictionGUIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {530, 530};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {171, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SVRPredictionGUIUIFigure = uifigure('Visible', 'off');
            app.SVRPredictionGUIUIFigure.Name = 'SVR Prediction GUI';
            app.SVRPredictionGUIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SVRPredictionGUIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelNameLabel = uilabel(app.LeftPanel);
            app.ModelNameLabel.Text = 'Model is not selected';
            app.SelectButton = uibutton(app.LeftPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SpinnerDays = uispinner(app.LeftPanel);
            app.SpinnerDays.Limits = [1 5];
            app.SpinnerDays.Value = 1;
            app.DaystopredictLabel = uilabel(app.LeftPanel);
            app.DaystopredictLabel.Text = 'Days to predict';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Dtlabel = uilabel(app.LeftPanel);
            app.Dtlabel.Text = 'Data is not selected';
            app.SelectButtonDt = uibutton(app.LeftPanel, 'push');
            app.SelectButtonDt.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonDtPushed, true);
            app.SelectButtonDt.Text = 'Select';
            app.errorLabel = uilabel(app.LeftPanel);
            app.errorLabel.Visible = 'off';
            app.errorLabel.Text = 'error';
            app.CreatedbyVladislavSorokinLabel = uilabel(app.LeftPanel);
            app.CreatedbyVladislavSorokinLabel.Text = 'Created by Vladislav Sorokin';
            app.SVRPREDICTIONLabel = uilabel(app.LeftPanel);
            app.SVRPREDICTIONLabel.Text = {'SVR'; 'PREDICTION'};
            app.RightPanel = uipanel(app.GridLayout);
            app.PredictionUi = uiaxes(app.RightPanel);
            title(app.PredictionUi, 'Prediction')
            xlabel(app.PredictionUi, 'Date')
            ylabel(app.PredictionUi, 'Closed Price')
            app.PredictionUi.Visible = 'off';
            app.PercentageerrorsLabel = uilabel(app.RightPanel);
            app.PercentageerrorsLabel.Visible = 'off';
            app.PercentageerrorsLabel.Text = 'Percentage errors';
            app.Error1Label = uilabel(app.RightPanel);
            app.Error1Label.Visible = 'off';
            app.Error1Label.Text = 'Error1';
            app.Error2Label = uilabel(app.RightPanel);
            app.Error2Label.Visible = 'off';
            app.Error2Label.Text = 'Error2';
            app.Error3Label = uilabel(app.RightPanel);
            app.Error3Label.Visible = 'off';
            app.Error3Label.Text = 'Error3';
            app.Error4Label = uilabel(app.RightPanel);
            app.Error4Label.Visible = 'off';
            app.Error4Label.Text = 'Error4';
            app.Error5Label = uilabel(app.RightPanel);
            app.Error5Label.Visible = 'off';
            app.Error5Label.Text = 'Error5';
            app.MSELabel = uilabel(app.RightPanel);
            app.MSELabel.Visible = 'off';
            app.MSELabel.Text = 'MSE';
            app.MapeLabel = uilabel(app.RightPanel);
            app.MapeLabel.Visible = 'off';
            app.MapeLabel.Text = 'Mape';
            % Show the figure after all components are created
            app.SVRPredictionGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = predict_svr_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SVRPredictionGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SVRPredictionGUIUIFigure)
        end
    end
end