classdef train_svr_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SVRTrainGUIUIFigure             matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ModelNameEditFieldLabel         matlab.ui.control.Label
        ModNameField                    matlab.ui.control.EditField
        StartButton                     matlab.ui.control.Button
        CreatedbyVladislavSorokinLabel  matlab.ui.control.Label
        AutoHyperCheckBox               matlab.ui.control.CheckBox
        SelectDtButton                  matlab.ui.control.Button
        DtLabel                         matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        MaxIterLabel                    matlab.ui.control.Label
        IterationSpinner                matlab.ui.control.Spinner
        KernelDropDownLabel             matlab.ui.control.Label
        KernelDropDown                  matlab.ui.control.DropDown
        SVRTRAININGLabel                matlab.ui.control.Label
        MaxObjectiveEvaluationsLabel    matlab.ui.control.Label
        MOESpinner                      matlab.ui.control.Spinner
        EpsilonLabel                    matlab.ui.control.Label
        EpsilonSpiner                   matlab.ui.control.Spinner
        GammaloopsSpinnerLabel          matlab.ui.control.Label
        GammaloopsSpinner               matlab.ui.control.Spinner
        CloopsSpinnerLabel              matlab.ui.control.Label
        CloopsSpinner                   matlab.ui.control.Spinner
        RightPanel                      matlab.ui.container.Panel
        PredictionUi                    matlab.ui.control.UIAxes
        MseLabel                        matlab.ui.control.Label
        MseResultLabel                  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        FullPathToDt
        isDtSelected = false
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDtButton
        function SelectDtButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');
            if isequal(file,0)
            else
               app.DtLabel.Text = file;
               app.FullPathToDt = fullfile(path,file);
               app.isDtSelected = true;
            end
            drawnow;
            figure(app.SVRTrainGUIUIFigure)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             if (app.isDtSelected ~= true)
                app.ErrorLabel.Visible = true;
                app.ErrorLabel.Text = "Data is not selected";
                return;
             elseif (isempty(app.ModNameField.Value))
                app.ErrorLabel.Visible = true;
                app.ErrorLabel.Text = "Enter model name";
                return;
             end            
            app.PredictionUi.Visible = true;
            cla(app.PredictionUi);
            app.ErrorLabel.Visible = false;
            [datespr, ypr, rst, mse] = train_svr(app.FullPathToDt,...
               app.IterationSpinner.Value,app.ModNameField.Value,...
               app.AutoHyperCheckBox.Value,app.KernelDropDown.Value,...
               app.MOESpinner.Value, app.EpsilonSpiner.Value,...
                app.GammaloopsSpinner.Value,...
                app.CloopsSpinner.Value);
            app.MseLabel.Visible = true;
            app.MseResultLabel.Visible = true;
            app.MseResultLabel.Text = num2str(mse);
            hold (app.PredictionUi, 'on')
            plot(app.PredictionUi,datespr(:,1),ypr,'-*')
            
            plot(app.PredictionUi,datespr(:,1),rst, '-o')
            hold (app.PredictionUi, 'off')
            legend(app.PredictionUi, 'Actual Value','Predicted Value');
        end

        % Value changed function: KernelDropDown
        function KernelDropDownValueChanged(app, event)
            value = app.KernelDropDown.Value;
        end

        % Value changed function: AutoHyperCheckBox
        function AutoHyperCheckBoxValueChanged(app, event)
            value = app.AutoHyperCheckBox.Value;
            if (value == true)
                app.KernelDropDown.Enable = false;
                app.MOESpinner.Enable = true;
                app.EpsilonSpiner.Enable = false;
                app.GammaloopsSpinner.Enable = false;
                app.CloopsSpinner.Enable = false;
            else
                app.KernelDropDown.Enable = true;
                app.MOESpinner.Enable = false;
                app.EpsilonSpiner.Enable = true;
                app.GammaloopsSpinner.Enable = true;
                app.CloopsSpinner.Enable = true;
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SVRTrainGUIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {544, 544};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SVRTrainGUIUIFigure = uifigure('Visible', 'off');
            app.SVRTrainGUIUIFigure.Name = 'SVR Train GUI';
            app.SVRTrainGUIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SVRTrainGUIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModelNameEditFieldLabel = uilabel(app.LeftPanel);
            app.ModelNameEditFieldLabel.Text = 'Model Name';
            app.ModNameField = uieditfield(app.LeftPanel, 'text');
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CreatedbyVladislavSorokinLabel = uilabel(app.LeftPanel);
            app.CreatedbyVladislavSorokinLabel.Text = 'Created by Vladislav Sorokin';
            app.AutoHyperCheckBox = uicheckbox(app.LeftPanel);
            app.AutoHyperCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoHyperCheckBoxValueChanged, true);
            app.AutoHyperCheckBox.Text = {'Search among all'; 'kernels and parameters'; 'using Bayesian optimization'};
            app.SelectDtButton = uibutton(app.LeftPanel, 'push');
            app.SelectDtButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDtButtonPushed, true);
            app.SelectDtButton.Text = 'Select';
            app.DtLabel = uilabel(app.LeftPanel);
            app.DtLabel.Text = 'Data is not selected';
            app.ErrorLabel = uilabel(app.LeftPanel);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error';
            app.MaxIterLabel = uilabel(app.LeftPanel);
            app.MaxIterLabel.Text = 'Max Iter';
            app.IterationSpinner = uispinner(app.LeftPanel);
            app.IterationSpinner.Limits = [1 Inf];
            app.IterationSpinner.Value = 1;
            app.KernelDropDownLabel = uilabel(app.LeftPanel);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.LeftPanel);
            app.KernelDropDown.Items = {'gaussian', 'linear'};
            app.KernelDropDown.ValueChangedFcn = createCallbackFcn(app, @KernelDropDownValueChanged, true);
            app.KernelDropDown.Value = 'gaussian';
            app.SVRTRAININGLabel = uilabel(app.LeftPanel);
            app.SVRTRAININGLabel.Text = 'SVR TRAINING';
            app.MaxObjectiveEvaluationsLabel = uilabel(app.LeftPanel);
            app.MaxObjectiveEvaluationsLabel.Text = {'Max'; 'Objective'; 'Evaluations'};
            app.MOESpinner = uispinner(app.LeftPanel);
            app.MOESpinner.Limits = [1 Inf];
            app.MOESpinner.Enable = 'off';
            app.MOESpinner.Value = 30;
            app.EpsilonLabel = uilabel(app.LeftPanel);
            app.EpsilonLabel.Text = 'Epsilon';
            app.EpsilonSpiner = uispinner(app.LeftPanel);
            app.EpsilonSpiner.Step = 0.1;
            app.EpsilonSpiner.Limits = [0 Inf];
            app.EpsilonSpiner.Value = 0.1;
            app.GammaloopsSpinnerLabel = uilabel(app.LeftPanel);
            app.GammaloopsSpinnerLabel.Text = {'Gamma '; 'loops'};
            app.GammaloopsSpinner = uispinner(app.LeftPanel);
            app.GammaloopsSpinner.Limits = [1 Inf];
            app.GammaloopsSpinner.Value = 10;
            app.CloopsSpinnerLabel = uilabel(app.LeftPanel);
            app.CloopsSpinnerLabel.Text = 'C loops';
            app.CloopsSpinner = uispinner(app.LeftPanel);
            app.CloopsSpinner.Limits = [1 Inf];
            app.CloopsSpinner.Value = 10;
            app.RightPanel = uipanel(app.GridLayout);
            app.PredictionUi = uiaxes(app.RightPanel);
            title(app.PredictionUi, 'Prediction')
            xlabel(app.PredictionUi, 'Date')
            ylabel(app.PredictionUi, 'Closed Price')
            app.PredictionUi.Visible = 'off';
            app.MseLabel = uilabel(app.RightPanel);
            app.MseLabel.Visible = 'off';
            app.MseLabel.Text = 'Mse:';
            app.MseResultLabel = uilabel(app.RightPanel);
            app.MseResultLabel.Visible = 'off';
            app.MseResultLabel.Text = 'MseResult';
            % Show the figure after all components are created
            app.SVRTrainGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = train_svr_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SVRTrainGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SVRTrainGUIUIFigure)
        end
    end
end